classdef IPPR_ASSIGNMENT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FakeCurrencyDetectionPanel  matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        DetectButton                matlab.ui.control.Button
        UploadImageFileButton       matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageFileButton
        function UploadImageFileButtonPushed(app, event)
                global image1;
                [filename pathname] = uigetfile({'".jpg'},"Open File");
                fullpathname = strcat(pathname,filename);
                image1 = imread(fullpathname);
                imshow(image1,'parent',app.UIAxes);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            global image1;

            % Check if an image is loaded
            if isempty(image1)
                disp('Please upload an image first.');
                return;
            end
        
            % Call your fake currency detection function
            fakeCurrencyDetected = detectFakeCurrency(image1);
        
            % Display the result in the UI
            if fakeCurrencyDetected
                disp('Fake Currency Detected.');
            else
                disp('No Fake Currency Detected.');
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FakeCurrencyDetectionPanel = uipanel(app.UIFigure);
            app.FakeCurrencyDetectionPanel.Title = 'Fake Currency Detection';
            app.UIAxes = uiaxes(app.FakeCurrencyDetectionPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Panel = uipanel(app.FakeCurrencyDetectionPanel);
            app.UploadImageFileButton = uibutton(app.Panel, 'push');
            app.UploadImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageFileButtonPushed, true);
            app.UploadImageFileButton.Text = 'Upload Image File';
            app.DetectButton = uibutton(app.Panel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPPR_ASSIGNMENT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end